classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        TabGroup         matlab.ui.container.TabGroup
        DebugTab         matlab.ui.container.Tab
        EditField2       matlab.ui.control.EditField
        EditField2Label  matlab.ui.control.Label
        TareButton       matlab.ui.control.Button
        PortDropDown     matlab.ui.control.DropDown
        Label            matlab.ui.control.Label
        SyncLamp         matlab.ui.control.Lamp
        GPIOLampLabel    matlab.ui.control.Label
        ConnectButton    matlab.ui.control.Button
        StatusLabel      matlab.ui.control.Label
        StopButton       matlab.ui.control.Button
        StartButton      matlab.ui.control.Button
        UIAxes           matlab.ui.control.UIAxes
        ExperimentTab    matlab.ui.container.Tab
        EditField        matlab.ui.control.EditField
        EditFieldLabel   matlab.ui.control.Label
        FingerDropDown   matlab.ui.control.DropDown
        Label_5          matlab.ui.control.Label
        SyncLamp_2       matlab.ui.control.Lamp
        GPIOLampLabel_2  matlab.ui.control.Label
        StopButton_2     matlab.ui.control.Button
        StartButton_2    matlab.ui.control.Button
        MvcEditField     matlab.ui.control.NumericEditField
        Label_4          matlab.ui.control.Label
        ModeDropDown     matlab.ui.control.DropDown
        Label_3          matlab.ui.control.Label
        UIAxes2          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Device      % 硬件通信适配器实例
        ForceLines  % 存储 10 条 AnimatedLine 的数组
        DataBuffer  % 真实数据缓存
        StartTime   % 记录实验开始的相对时间

        % 传感器映射字典：定义食指、中指、无名指对应的上下传感器索引 (在 10 个数据通道中的位置)
        % 假设格式: [Flexion_Index, Extension_Index]
        SensorMap = containers.Map({'Index', 'Middle', 'Ring'}, {[1, 6], [2, 7], [3, 8]});

        % MVC 缓存字典：存储各手指测得的最大随意收缩力绝对值
        MvcCache = containers.Map({'Index', 'Middle', 'Ring'}, {1.0, 1.0, 1.0}); % 初始占位防除零

        CurrentTaskFinger = 'Index';  % 当前选中的手指
        CurrentTaskType = 'Trapezoid'; % 当前任务类型

        TargetCurveLine  % 目标曲线对象 (虚线)
        TrackingLine     % 实时跟踪曲线对象 (实线)

        ExperimentStartTime = -1; % 实验阶段相对零点
        IsExperimentRunning = false; % 实验状态机标志
    end
    
    methods (Access = private)function generateTargetCurve(app)
            if ~isempty(app.TargetCurveLine) && isvalid(app.TargetCurveLine)
                delete(app.TargetCurveLine);
            end
            
            % 构建单指 100s 目标序列
            t1 = [0, 1, 2, 5, 6, 9.5, 10.5, 12.5, 13.5, 18, 19, 21, 22, 25];
            f1 = [0, 0, 10, 10, 0, 0,   20,   20,   0,    0,  30, 30, 0,  0];
            t_rest1 = [25, 40]; f_rest1 = [0, 0];
            t2 = t1 + 40; f2 = -f1;
            t_rest2 = [65, 80]; f_rest2 = [0, 0];
            t3 = [0, 5, 10, 15, 20] + 80; f3 = [0, 20, 0, -20, 0];
            
            t_total = [t1, t_rest1, t2, t_rest2, t3];
            f_total = [f1, f_rest1, f2, f_rest2, f3];
            
            % 在 UIAxes2 上绘制背景目标线
            app.TargetCurveLine = plot(app.UIAxes2, t_total, f_total, '--', 'Color', [0.5 0.5 0.5], 'LineWidth', 2);
            app.UIAxes2.XLim = [0, 100];
            app.UIAxes2.YLim = [-40, 40];
        end

        function handleIncomingData(app, data)
            % --- 1. 全局数据缓存与 Debug 页面渲染 ---
            app.DataBuffer = [app.DataBuffer; data];
            if app.StartTime == -1
                app.StartTime = data(1);
            end
            debugTime = (data(1) - app.StartTime) / 1000; 
            forces = data(3:12);
            for i = 1:10
                addpoints(app.ForceLines(i), debugTime, forces(i));
            end
            if debugTime > 10
                app.UIAxes.XLim = [debugTime - 10, debugTime];
            end
            
            % --- 2. 实验逻辑状态机 ---
            if ~app.IsExperimentRunning
                drawnow limitrate;
                return;
            end
            
            if app.ExperimentStartTime == -1
                app.ExperimentStartTime = data(1);
            end
            expTime = (data(1) - app.ExperimentStartTime) / 1000;
            
            % 计算当前手指净力
            sensorIdx = app.SensorMap(app.CurrentTaskFinger);
            netForce = data(2 + sensorIdx(1)) - data(2 + sensorIdx(2));
            
            % 模式分流
            if strcmp(app.CurrentTaskType, 'MVC标定')
                if expTime <= 2.0
                    % 更新 MVC 缓存
                    currentMax = app.MvcCache(app.CurrentTaskFinger);
                    app.MvcCache(app.CurrentTaskFinger) = max(currentMax, abs(netForce));
                    
                    % 实时更新 UI 上的 MVC 数值 (接入 app.MvcEditField)
                    app.MvcEditField.Value = app.MvcCache(app.CurrentTaskFinger);
                    addpoints(app.TrackingLine, expTime, abs(netForce));
                else
                    app.StopButton_2Pushed(); % 2秒结束
                    app.UIAxes2.Title.String = sprintf('[%s] 标定完成', app.CurrentTaskFinger);
                end
                
            elseif strcmp(app.CurrentTaskType, '力跟踪')
                if expTime > 300
                    app.StopButton_2Pushed();
                    app.UIAxes2.Title.String = '力跟踪实验全部完成';
                    return;
                end
                
                % 计算所处阶段 (0-99 食指, 100-199 中指, 200-299 无名指)
                fingerIdx = floor(expTime / 100) + 1;
                fingers = {'Index', 'Middle', 'Ring'};
                targetFinger = fingers{fingerIdx};
                
                % 换指判定
                if ~strcmp(app.CurrentTaskFinger, targetFinger)
                    app.CurrentTaskFinger = targetFinger;
                    % 自动更新下拉菜单，提示主试/受试者当前换到了哪根手指 (接入 app.FingerDropDown)
                    app.FingerDropDown.Value = targetFinger;
                    clearpoints(app.TrackingLine);
                end
                
                % 保持显示当前手指对应的基准 MVC 值 (接入 app.MvcEditField)
                mvcValue = app.MvcCache(app.CurrentTaskFinger);
                app.MvcEditField.Value = mvcValue;
                
                t_local = mod(expTime, 100);
                percentMvc = (netForce / mvcValue) * 100;
                addpoints(app.TrackingLine, t_local, percentMvc);
                
                % 判定动作状态
                if (t_local > 25 && t_local <= 40) || (t_local > 65 && t_local <= 80)
                    phase_status = '休息中';
                elseif t_local <= 25
                    phase_status = '梯形下压跟跑';
                elseif t_local > 40 && t_local <= 65
                    phase_status = '梯形上抬跟跑';
                else
                    phase_status = '三角波连续跟跑';
                end
                
                % 状态文字输出到图表标题
                app.UIAxes2.Title.String = sprintf('当前状态: %s - %s', app.CurrentTaskFinger, phase_status);
            end
            
            drawnow limitrate;
        end

        function handleStatusMessage(app, msg)
            if strcmp(msg, "TareSuccess")
                app.EditField.Value = '所有传感器已去皮重';
                % 可以闪烁一下指示灯作为视觉反馈
                app.SyncLamp_2.Color = 'cyan';
                pause(0.5);
                app.SyncLamp_2.Color = 'green';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 扫描串口
            ports = serialportlist("available");
            if isempty(ports)
                app.PortDropDown.Items = {'无可用串口'};
                app.StatusLabel.Text = '状态: 未检测到设备';
            else
                app.PortDropDown.Items = ports;
                app.StatusLabel.Text = '状态: 等待连接';
            end

            % 初始化图表
            app.UIAxes.XLim = [0, 10]; 
            app.UIAxes.XGrid = 'on'; app.UIAxes.YGrid = 'on';
            
            % 预分配波形线
            colors = lines(10);
            app.ForceLines = gobjects(1, 10);
            for i = 1:10
                app.ForceLines(i) = animatedline(app.UIAxes, 'Color', colors(i,:), 'LineWidth', 1.2);
            end
            
            app.DataBuffer = [];
            app.SyncLamp.Color = [0.8 0.8 0.8];
        end

        % Callback function
        function UIAxesButtonDown(app, event)
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed2(app, event)
            if isempty(app.Device), return; end
            
            app.Device.StopRecording();
            app.SyncLamp.Color = [0.8 0.8 0.8];
            app.StatusLabel.Text = '状态: 停止，正在保存数据';
            
            if ~isempty(app.DataBuffer)
                filename = sprintf('Experiment_%s.csv', datestr(now, 'yyyymmdd_HHMMSS'));
                T = array2table(app.DataBuffer, 'VariableNames', ...
                    {'Timestamp_ms', 'Angle', 'L1','L2','L3','L4','L5','R1','R2','R3','R4','R5'});
                writetable(T, filename);
                app.StatusLabel.Text = ['状态: 数据已存至 ', filename];
            end
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            if strcmp(app.ConnectButton.Text, '连接设备')
                port = app.PortDropDown.Value;
                if strcmp(port, '无可用串口'), return; end
                
                try
                    app.Device = SerialDevice(port, 115200);
                    % 绑定底层数据驱动到自定义处理函数
                    app.Device.DataCallback = @app.handleIncomingData;
                    
                    % 绑定状态信息回调
                    app.Device.StatusCallback = @app.handleStatusMessage;

                    app.ConnectButton.Text = '断开连接';
                    app.StatusLabel.Text = ['状态: 已连接至 ', char(port)];
                    app.PortDropDown.Enable = 'off';
                catch ME
                    uialert(app.UIFigure, ME.message, '连接失败');
                end
            else
                delete(app.Device);
                app.Device = [];
                app.ConnectButton.Text = '连接设备';
                app.StatusLabel.Text = '状态: 已断开';
                app.PortDropDown.Enable = 'on';
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if isempty(app.Device), return; end
            
            app.DataBuffer = [];
            for i = 1:10, clearpoints(app.ForceLines(i)); end
            app.StartTime = -1;
            app.UIAxes.XLim = [0, 10];
            
            app.Device.StartRecording();
            app.SyncLamp.Color = 'green';
            app.StatusLabel.Text = '状态: 正在采集...';
        end

        % Button pushed function: StopButton_2
        function StopButton_2Pushed(app, event)
            app.IsExperimentRunning = false;
            app.SyncLamp_2.Color = [0.8 0.8 0.8];
            app.EditField.Value = '实验已停止';
        end

        % Button pushed function: StartButton_2
        function StartButton_2Pushed(app, event)
            if isempty(app.Device)
                uialert(app.UIFigure, '请先在Debug页面连接并启动设备', '设备未就绪');
                return;
            end
            
            app.CurrentTaskType = app.ModeDropDown.Value; 
            app.SyncLamp_2.Color = 'green'; 
            app.ExperimentStartTime = -1; % 重置时间零点
            cla(app.UIAxes2);
            hold(app.UIAxes2, 'on');
            
            if strcmp(app.CurrentTaskType, '力跟踪')
                app.generateFullTargetCurve();
                app.TrackingLine = animatedline(app.UIAxes2, 'Color', 'b', 'LineWidth', 1.5);
                
                % 力跟踪模式：默认从食指开始，强制覆盖 DropDown 显示为 Index
                app.CurrentTaskFinger = 'Index'; 
                app.FingerDropDown.Value = 'Index';
                app.UIAxes2.Title.String = '力跟踪准备就绪，即将开始...';
            else
                % MVC 标定模式：读取 DropDown 中你手动选中的手指 (接入 app.FingerDropDown)
                app.CurrentTaskFinger = app.FingerDropDown.Value;
                
                app.UIAxes2.XLim = [0, 2];
                app.TrackingLine = animatedline(app.UIAxes2, 'Color', 'r', 'LineWidth', 1.5);
                app.MvcCache(app.CurrentTaskFinger) = 0.1; % 初始化极小值防除0
                
                % 清零 MvcEditField 以备显示新一轮标定数据 (接入 app.MvcEditField)
                app.MvcEditField.Value = 0; 
                app.UIAxes2.Title.String = sprintf('正在标定 [%s] MVC ...', app.CurrentTaskFinger);
            end
            
            app.IsExperimentRunning = true;
        end

        % Button pushed function: TareButton
        function TareButtonPushed(app, event)
            % Button pushed function: TareButton
            if isempty(app.Device)
                uialert(app.UIFigure, '请先连接设备', '错误');
                return;
            end
            app.Device.SendCommand("T");
            app.EditField2.Value = '正在发送去皮指令...';

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DebugTab = uitab(app.TabGroup);
            app.DebugTab.Title = 'Debug';
            app.UIAxes = uiaxes(app.DebugTab);
            title(app.UIAxes, '10路传感器实时波形')
            xlabel(app.UIAxes, '时间(s)')
            ylabel(app.UIAxes, '读取值')
            zlabel(app.UIAxes, 'Z')
            app.StartButton = uibutton(app.DebugTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'StartButton';
            app.StopButton = uibutton(app.DebugTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed2, true);
            app.StopButton.Text = 'StopButton';
            app.StatusLabel = uilabel(app.DebugTab);
            app.StatusLabel.Text = '状态：等待连接...';
            app.ConnectButton = uibutton(app.DebugTab, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = '连接设备';
            app.GPIOLampLabel = uilabel(app.DebugTab);
            app.GPIOLampLabel.Text = 'GPIO同步信号';
            app.SyncLamp = uilamp(app.DebugTab);
            app.Label = uilabel(app.DebugTab);
            app.Label.Text = '串口号';
            app.PortDropDown = uidropdown(app.DebugTab);
            app.TareButton = uibutton(app.DebugTab, 'push');
            app.TareButton.ButtonPushedFcn = createCallbackFcn(app, @TareButtonPushed, true);
            app.TareButton.Text = 'TareButton';
            app.EditField2Label = uilabel(app.DebugTab);
            app.EditField2Label.Text = 'Edit Field2';
            app.EditField2 = uieditfield(app.DebugTab, 'text');
            app.ExperimentTab = uitab(app.TabGroup);
            app.ExperimentTab.Title = 'Experiment';
            app.UIAxes2 = uiaxes(app.ExperimentTab);
            title(app.UIAxes2, '单指力追踪（% MVC）')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes2.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Label_3 = uilabel(app.ExperimentTab);
            app.Label_3.Text = '实验模式';
            app.ModeDropDown = uidropdown(app.ExperimentTab);
            app.ModeDropDown.Items = {'MVC标定', '力跟踪'};
            app.ModeDropDown.Value = 'MVC标定';
            app.Label_4 = uilabel(app.ExperimentTab);
            app.Label_4.Text = '测得MVC值';
            app.MvcEditField = uieditfield(app.ExperimentTab, 'numeric');
            app.StartButton_2 = uibutton(app.ExperimentTab, 'push');
            app.StartButton_2.ButtonPushedFcn = createCallbackFcn(app, @StartButton_2Pushed, true);
            app.StartButton_2.Text = 'StartButton';
            app.StopButton_2 = uibutton(app.ExperimentTab, 'push');
            app.StopButton_2.ButtonPushedFcn = createCallbackFcn(app, @StopButton_2Pushed, true);
            app.StopButton_2.Text = 'StopButton';
            app.GPIOLampLabel_2 = uilabel(app.ExperimentTab);
            app.GPIOLampLabel_2.Text = 'GPIO同步信号';
            app.SyncLamp_2 = uilamp(app.ExperimentTab);
            app.Label_5 = uilabel(app.ExperimentTab);
            app.Label_5.Text = '当前手指';
            app.FingerDropDown = uidropdown(app.ExperimentTab);
            app.FingerDropDown.Items = {'Index', 'Middle', 'Ring'};
            app.FingerDropDown.Value = 'Index';
            app.EditFieldLabel = uilabel(app.ExperimentTab);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.ExperimentTab, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end